classdef readFiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        ArchivosButton  matlab.ui.control.Button
        UITable         matlab.ui.control.Table
        RightPanel      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        fileName;
        pathName;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ArchivosButton
        function ArchivosButtonPushed(app, event)
            [fileNames] = uigetfile("*.sig","MultiSelect","on");  
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            fileNames = fileNames.';
            app.UITable.Data = fileNames;  
            
            t1 = {};
            t2 = {};
            t3 = {};
            t4 = {};
            
            
            for i = 1:numel(fileNames)
                finder = contains(fileNames(i),'T1');
                if(finder == 1)
                    t1 = [t1, fileNames(i)];
                end
                finder = contains(fileNames(i),'T2');
                if(finder == 1)
                    t2 = [t2, fileNames(i)];
                end
                finder = contains(fileNames(i),'T3');
                if(finder == 1)
                    t3 = [t3, fileNames(i)];
                end
                finder = contains(fileNames(i),'T4');
                if(finder == 1)
                    t4 = [t4 , fileNames(i)];
                end
            end
            
            for i = 1:numel(t1)
                disp(t1(i))
            end
            
            disp('--------------uwu--------------')
            
            for i = 1:numel(t2)
                disp(t2(i))
            end
            
            disp('--------------uwu--------------')
            
            for i = 1:numel(t3)
                disp(t3(i))
            end
            
            disp('--------------uwu--------------')
            
            for i = 1:numel(t4)
                disp(t4(i))
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ArchivosButton = uibutton(app.LeftPanel, 'push');
            app.ArchivosButton.ButtonPushedFcn = createCallbackFcn(app, @ArchivosButtonPushed, true);
            app.ArchivosButton.Text = 'Archivos';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Archivos'};
            app.UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = readFiles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end